classdef GuiLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoggerUIFigure         matlab.ui.Figure
        SavecontCheckBox       matlab.ui.control.CheckBox
        SaverecordButton       matlab.ui.control.Button
        ClearrecordButton      matlab.ui.control.Button
        Axes                   matlab.ui.control.UIAxes
        LogfileEditFieldLabel  matlab.ui.control.Label
        LogfileEditField       matlab.ui.control.EditField
        PeriodsLabel           matlab.ui.control.Label
        MeasperiodsEditField   matlab.ui.control.NumericEditField
        MeasButton             matlab.ui.control.StateButton
        MeasurementLabel       matlab.ui.control.Label
        ReadingsTextArea       matlab.ui.control.TextArea
        logscaleYCheckBox      matlab.ui.control.CheckBox
    end


    properties (Access = public)
        name = 'Logger' % Name of the logger window
        linked_elem_list = {}; % List of elements updated by updateGui
        Lg % Logger object
        y_label = '';
    end

    properties (Access = private)
        Llh % Listener to the logger NewData event 
    end

    methods (Access = private)
    
        function createControlLinks(app)
            app.LogfileEditField.Tag = 'save_file';
            app.linked_elem_list = [app.linked_elem_list, app.LogfileEditField];
            
            app.SavecontCheckBox.Tag = 'save_cont';
            app.linked_elem_list = [app.linked_elem_list, app.SavecontCheckBox];
            
            app.MeasperiodsEditField.Tag = 'MeasTimer.Period';
            app.linked_elem_list = [app.linked_elem_list, app.MeasperiodsEditField];
            
            app.MeasButton.Tag = 'MeasTimer.Running';
            addprop(app.MeasButton,'OutputProcessingFcn');
            app.MeasButton.OutputProcessingFcn = @(str)setMeasButton(app,str);
            app.linked_elem_list = [app.linked_elem_list, app.MeasButton];
        end
        
         function updateDataDisp(app)
            cla(app.Axes);
            try
                mdata = cell2mat(app.Lg.data);
            catch
                mdata = [];
                warning(['Cannot display logger data, '...
                    'possibly because of data dimensions being different ',...
                    'at different times. Can try crlearing data to resolve.'])
            end       
            if app.logscaleYCheckBox.Value
                app.Axes.YScale = 'log';
            else
                app.Axes.YScale = 'linear';
            end
            hold(app.Axes,'on');
            [~, m] = size(mdata);
            for i=1:m   
                plot(app.Axes, app.Lg.timestamps, mdata(:,i));
            end
            hold(app.Axes,'off');
            if m>=1
                legend(app.Axes,app.Lg.data_headers{:},'Location','southwest');
                ylabel(app.Axes, app.y_label);
            end
            
            app.ReadingsTextArea.Value = dispLastReading(app.Lg);
        end
    
        function val = setMeasButton(app, str)
            if strcmpi(str,'on') 
                val = 1;
                app.MeasButton.Text = 'Stop';
            else 
                val = 0;
                app.MeasButton.Text = 'Start';
            end
        end
    
        function newDataCallback(app,~,~)
            updateDataDisp(app);
            updateGui(app, app.Lg);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            p=inputParser();
            p.KeepUnmatched = true;
            addParameter(p,'Lg',[]);
            addParameter(p,'name',[]);
            parse(p, varargin{:});
            
            if ~ismember('Lg',p.UsingDefaults)
                app.Lg = p.Results.Lg;
            else
                % Create a logger, if it was not supplied externally
                app.Lg = MyLogger();
            end
            if ~ismember('name',p.UsingDefaults)
                app.name = p.Results.name;
            end
            app.LoggerUIFigure.Name = app.name;
            
            app.Llh = addlistener(app.Lg, 'NewData', @app.newDataCallback);
            
            createControlLinks(app);
            updateGui(app, app.Lg);
            updateDataDisp(app);
        end

        % Value changed function: LogfileEditField
        function LogfileEditFieldValueChanged(app, event)
            value = app.LogfileEditField.Value;
            app.Lg.save_file = value;
        end

        % Value changed function: MeasButton
        function MeasButtonValueChanged(app, event)
            value = app.MeasButton.Value;
            if value
                start(app.Lg)
                app.MeasperiodsEditField.Editable='off';
            else
                stop(app.Lg)
                app.MeasperiodsEditField.Editable='on';
            end
            updateGui(app, app.Lg);
            updateDataDisp(app);
        end

        % Value changed function: SavecontCheckBox
        function SavecontCheckBoxValueChanged(app, event)
            value = app.SavecontCheckBox.Value;
            app.Lg.save_cont = value;
        end

        % Button pushed function: ClearrecordButton
        function ClearrecordButtonPushed(app, event)
            clearLog(app.Lg);
            updateGui(app, app.Lg);
            updateDataDisp(app);
        end

        % Button pushed function: SaverecordButton
        function SaverecordButtonPushed(app, event)
            saveLog(app.Lg);
        end

        % Value changed function: MeasperiodsEditField
        function MeasperiodsEditFieldValueChanged(app, event)
            value = app.MeasperiodsEditField.Value;
            app.Lg.MeasTimer.Period = value;
        end

        % Close request function: LoggerUIFigure
        function LoggerUIFigureCloseRequest(app, event)
            delete(app.Lg);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoggerUIFigure = uifigure;
            app.LoggerUIFigure.Name = 'Logger';
            app.LoggerUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoggerUIFigureCloseRequest, true);
            app.SavecontCheckBox = uicheckbox(app.LoggerUIFigure);
            app.SavecontCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavecontCheckBoxValueChanged, true);
            app.SavecontCheckBox.Text = 'Save continuously';
            app.SaverecordButton = uibutton(app.LoggerUIFigure, 'push');
            app.SaverecordButton.ButtonPushedFcn = createCallbackFcn(app, @SaverecordButtonPushed, true);
            app.SaverecordButton.Text = 'Save record';
            app.ClearrecordButton = uibutton(app.LoggerUIFigure, 'push');
            app.ClearrecordButton.ButtonPushedFcn = createCallbackFcn(app, @ClearrecordButtonPushed, true);
            app.ClearrecordButton.Text = 'Clear record';
            app.Axes = uiaxes(app.LoggerUIFigure);
            xlabel(app.Axes, 'Time')
            app.LogfileEditFieldLabel = uilabel(app.LoggerUIFigure);
            app.LogfileEditFieldLabel.Text = 'Log file';
            app.LogfileEditField = uieditfield(app.LoggerUIFigure, 'text');
            app.LogfileEditField.ValueChangedFcn = createCallbackFcn(app, @LogfileEditFieldValueChanged, true);
            app.LogfileEditField.Value = 'M:\Measurement Campaigns\2018-07-13 Pressure Log';
            app.PeriodsLabel = uilabel(app.LoggerUIFigure);
            app.PeriodsLabel.Text = 'Period, s';
            app.MeasperiodsEditField = uieditfield(app.LoggerUIFigure, 'numeric');
            app.MeasperiodsEditField.ValueChangedFcn = createCallbackFcn(app, @MeasperiodsEditFieldValueChanged, true);
            app.MeasButton = uibutton(app.LoggerUIFigure, 'state');
            app.MeasButton.ValueChangedFcn = createCallbackFcn(app, @MeasButtonValueChanged, true);
            app.MeasButton.Text = 'Start';
            app.MeasurementLabel = uilabel(app.LoggerUIFigure);
            app.MeasurementLabel.Text = 'Measurement';
            app.ReadingsTextArea = uitextarea(app.LoggerUIFigure);
            app.logscaleYCheckBox = uicheckbox(app.LoggerUIFigure);
            app.logscaleYCheckBox.Text = 'log scale Y';
            app.logscaleYCheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiLogger(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoggerUIFigure)
        end
    end
end